classdef balancing_of_masses_in_same_plane < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        BALANCINGOF3MASSESINSAMEPLANELabel  matlab.ui.control.Label
        mcEditField           matlab.ui.control.NumericEditField
        mcEditFieldLabel      matlab.ui.control.Label
        rcEditFieldLabel      matlab.ui.control.Label
        thetacEditFieldLabel  matlab.ui.control.Label
        thetacEditField       matlab.ui.control.NumericEditField
        CalculateButton       matlab.ui.control.Button
        rcEditField           matlab.ui.control.NumericEditField
        theta3EditField       matlab.ui.control.NumericEditField
        theta3EditFieldLabel  matlab.ui.control.Label
        theta2EditField       matlab.ui.control.NumericEditField
        theta2EditFieldLabel  matlab.ui.control.Label
        theta1EditField       matlab.ui.control.NumericEditField
        theta1EditFieldLabel  matlab.ui.control.Label
        r3EditField           matlab.ui.control.NumericEditField
        r3EditFieldLabel      matlab.ui.control.Label
        r2EditField           matlab.ui.control.NumericEditField
        r2EditFieldLabel      matlab.ui.control.Label
        r1EditField           matlab.ui.control.NumericEditField
        r1EditFieldLabel      matlab.ui.control.Label
        m3EditField           matlab.ui.control.NumericEditField
        m3EditFieldLabel      matlab.ui.control.Label
        m2EditField           matlab.ui.control.NumericEditField
        m2EditFieldLabel      matlab.ui.control.Label
        m1EditField           matlab.ui.control.NumericEditField
        m1EditFieldLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %masses
            m1=app.m1EditField.Value ;
            m2=app.m2EditField.Value ;
            m3=app.m3EditField.Value ;
            
            
            %distance from the center 
            r1=app.r1EditField.Value ;
            r2=app.r2EditField.Value ;
            r3=app.r3EditField.Value ;
            rc=app.rcEditField.Value ;
            
            % angle form the x-axis in CounterClockwise direction
            theta1=app.theta1EditField.Value ;
            theta2=app.theta2EditField.Value ;
            theta3=app.theta3EditField.Value ;
            

            mc=sqrt((m1*r1*cos(theta1)+m2*r2*cos(theta2)+m3*r3*cos(theta3))^2+(m1*r1*sin(theta1)+m2*r2*sin(theta2)+m3*r3*sin(theta3))^2)/rc;
            app.mcEditField.Value=mc ;
            thetac=atan((m1*r1*sin(theta1)+m2*r2*sin(theta2)+m3*r3*sin(theta3))/(m1*r1*cos(theta1)+m2*r2*cos(theta2)+m3*r3*cos(theta3)))*180/pi;
            app.thetacEditField.Value=thetac ;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.m1EditFieldLabel = uilabel(app.UIFigure);
            app.m1EditFieldLabel.Text = 'm1';
            app.m1EditField = uieditfield(app.UIFigure, 'numeric');
            app.m2EditFieldLabel = uilabel(app.UIFigure);
            app.m2EditFieldLabel.Text = 'm2';
            app.m2EditField = uieditfield(app.UIFigure, 'numeric');
            app.m3EditFieldLabel = uilabel(app.UIFigure);
            app.m3EditFieldLabel.Text = 'm3';
            app.m3EditField = uieditfield(app.UIFigure, 'numeric');
            app.r1EditFieldLabel = uilabel(app.UIFigure);
            app.r1EditFieldLabel.Text = 'r1';
            app.r1EditField = uieditfield(app.UIFigure, 'numeric');
            app.r2EditFieldLabel = uilabel(app.UIFigure);
            app.r2EditFieldLabel.Text = 'r2';
            app.r2EditField = uieditfield(app.UIFigure, 'numeric');
            app.r3EditFieldLabel = uilabel(app.UIFigure);
            app.r3EditFieldLabel.Text = 'r3';
            app.r3EditField = uieditfield(app.UIFigure, 'numeric');
            app.theta1EditFieldLabel = uilabel(app.UIFigure);
            app.theta1EditFieldLabel.Text = 'theta1';
            app.theta1EditField = uieditfield(app.UIFigure, 'numeric');
            app.theta2EditFieldLabel = uilabel(app.UIFigure);
            app.theta2EditFieldLabel.Text = 'theta2';
            app.theta2EditField = uieditfield(app.UIFigure, 'numeric');
            app.theta3EditFieldLabel = uilabel(app.UIFigure);
            app.theta3EditFieldLabel.Text = 'theta3';
            app.theta3EditField = uieditfield(app.UIFigure, 'numeric');
            app.rcEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.thetacEditField = uieditfield(app.UIFigure, 'numeric');
            app.thetacEditFieldLabel = uilabel(app.UIFigure);
            app.thetacEditFieldLabel.Text = 'thetac';
            app.rcEditFieldLabel = uilabel(app.UIFigure);
            app.rcEditFieldLabel.Text = 'rc';
            app.mcEditFieldLabel = uilabel(app.UIFigure);
            app.mcEditFieldLabel.Text = 'mc';
            app.mcEditField = uieditfield(app.UIFigure, 'numeric');
            app.BALANCINGOF3MASSESINSAMEPLANELabel = uilabel(app.UIFigure);
            app.BALANCINGOF3MASSESINSAMEPLANELabel.Text = 'BALANCING OF 3 MASSES IN SAME PLANE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = balancing_of_masses_in_same_plane

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end